classdef golfApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        HitBallButton            matlab.ui.control.Button
        ChooseHoleDropDown       matlab.ui.control.DropDown
        ChooseHoleDropDownLabel  matlab.ui.control.Label
        ChooseBallDropDown       matlab.ui.control.DropDown
        ChooseBallDropDownLabel  matlab.ui.control.Label
        DetectBoardButton        matlab.ui.control.Button
        UIAxes                   matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        image %Hold image 
        golf %Hold all of golf data
    end
    
    methods (Access = private)
        
        function plotGolf(app)
            %Grab each centroid, & at each centroid location on current image 
            %Compare the 3 pixels for RGB to determine color, identifying 
            %Golfballs & Golfholes 

            hold(app.UIAxes, 'on');
            for i = 1 : length(app.golf.stats) 
                centroid = app.golf.stats(i).Centroid; 
                plot(app.UIAxes, centroid(1),centroid(2),'kX');
                round(centroid(1));
                round(centroid(2));
                app.image(round(centroid(2)),round(centroid(1)),1);
                app.image(round(centroid(2)),round(centroid(1)),2);
                app.image(round(centroid(2)),round(centroid(1)),3);
            
                %~White: Golfball
                if app.image(round(centroid(2)),round(centroid(1)),1) > 240 && app.image(round(centroid(2)),round(centroid(1)),2) > 240
                    app.golf.numBall = app.golf.numBall + 1;
                    app.golf.locBall(app.golf.numBall, :) = [centroid(1), centroid(2)]; 
                    text(centroid(1)+5,centroid(2)+50,sprintf('BALL#%d', app.golf.numBall), 'Parent', app.UIAxes); 
            
                %Otherwise: Golfhole
                else 
                    app.golf.numHole = app.golf.numHole + 1; 
                    app.golf.locHole(app.golf.numHole, :) = [centroid(1), centroid(2)]; 
                    text(centroid(1)+5,centroid(2)+50,sprintf('HOLE#%d', app.golf.numHole), 'Parent', app.UIAxes); 
                end
            
            end 
            hold(app.UIAxes, 'off');
        end 
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: DetectBoardButton
        function DetectBoardButtonPushed(app, event)
            %hold(app.UIAxes, 'on');  % Hold for overlaying other elements
            run('sub_cam.m'); 
            app.golf = golf;
            app.image = app.golf.current; 
            imshow(app.image, 'Parent', app.UIAxes); 
            plotGolf(app); 
        end

        % Button pushed function: HitBallButton
        function HitBallButtonPushed(app, event)
            if ~isempty(app.golf)
            % Set Motor Angle
            x_distance = (614 - app.golf.locBall(1,1)) + 60.45;
            y_distance = app.golf.locBall(1,2) - 10;
            motor_angle = atand(y_distance/x_distance); 
            
            %Send data into Simulink Motor Model 
            open_system('MotorModel_Sp23_V22b');
            set_param('MotorModel_Sp23_V22b/desiredPosition', 'value', num2str(motor_angle));
            pause(10);
            motor_angle = 0;
            set_param('MotorModel_Sp23_V22b/desiredPosition', 'value', num2str(motor_angle)); 
            else 
                msgbox('Detect the GolfBoard first');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Golf Board')
            zlabel(app.UIAxes, 'Z')
            app.DetectBoardButton = uibutton(app.UIFigure, 'push');
            app.DetectBoardButton.ButtonPushedFcn = createCallbackFcn(app, @DetectBoardButtonPushed, true);
            app.DetectBoardButton.Text = 'Detect Board';
            app.ChooseBallDropDownLabel = uilabel(app.UIFigure);
            app.ChooseBallDropDownLabel.Text = 'Choose Ball';
            app.ChooseBallDropDown = uidropdown(app.UIFigure);
            app.ChooseBallDropDown.Items = {'Ball #1 ', 'Ball #2 ', 'Ball #3'};
            app.ChooseBallDropDown.Value = 'Ball #1 ';
            app.ChooseHoleDropDownLabel = uilabel(app.UIFigure);
            app.ChooseHoleDropDownLabel.Text = 'Choose Hole';
            app.ChooseHoleDropDown = uidropdown(app.UIFigure);
            app.ChooseHoleDropDown.Items = {'Hole #1 ', 'Hole #2 ', 'Hole #3'};
            app.ChooseHoleDropDown.Value = 'Hole #1 ';
            app.HitBallButton = uibutton(app.UIFigure, 'push');
            app.HitBallButton.ButtonPushedFcn = createCallbackFcn(app, @HitBallButtonPushed, true);
            app.HitBallButton.Text = 'Hit Ball';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = golfApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end